classdef blackjack1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        hitButton             matlab.ui.control.Button
        StandButton           matlab.ui.control.Button
        DOUBLEDOWNButton      matlab.ui.control.Button
        DEALButton            matlab.ui.control.Button
        DeckBack              matlab.ui.control.Image
        TextArea_2            matlab.ui.control.TextArea
        BalanceEditField      matlab.ui.control.NumericEditField
        BetEditField          matlab.ui.control.NumericEditField
        TextArea              matlab.ui.control.TextArea
        YouWonLabel           matlab.ui.control.Label
        YouLostLabel          matlab.ui.control.Label
        OutofMoneyLabel       matlab.ui.control.Label
        YouTiedLabel          matlab.ui.control.Label
        PlayAgainButton       matlab.ui.control.Button
        QuitPlayingButton     matlab.ui.control.Button
        GAMEOVERReplayButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        deck % the class deck (holds classes hand and card)
        xpos = 310; % xposition for player 1 location
        xpos2 = 310; % xposition for dealer card location
    end
    
    methods (Access = public)
            
        function check21(app, deck) %checks player = 21/BJ or player bust and sends win/lose/tie accordingly
            total = app.sumHand(app.deck);
            while total>21 %ace checker, changes ace 11 value to 1 when over 21
                for i=1:length(app.deck.p1Hand.cards)
                    if app.deck.p1Hand.cards(i).numVal == "11"
                        app.deck.p1Hand.cards(i).numVal = "1";
                        total = app.sumHand(app.deck);
                        break
                    end
                end
                if total <= 21 %breaks out of the ace checker and player did not bust
                    continue
                elseif total > 21 %breaks out of ace checker and player busts
                    break
                end
            end
            if total > 21 %lose condition as player busts
                app.flipDealerHand
                app.loseScreen
            elseif total == 21 %checking BJ or just 21 vs dealer getting BJ or just loses, manages tie if player gets 21 (not blackjack)
                app.flipDealerHand(app.deck) 
                boolDealer = app.checkDealerBJ; %check dealer BJ
                if boolDealer == true
                    if length(app.deck.p1Hand.cards) > 2 %check if player got 21, but not BJ
                        app.loseScreen %dealer BJ, player 21, player loses
                    else
                        app.tieScreen %check if player got BJ, tie since, both BJ
                    end
                elseif (boolDealer == false) && (length(app.deck.p1Hand.cards) == 2) %player BJ, but not dealer, so dealer loses
                    app.winScreen
                elseif (boolDealer == false) && (length(app.deck.p1Hand.cards) > 2) %player 21, so now dealer tries to tie
                    dtotal = app.sumDealer; %dealer draws for deal
                    while (dtotal < 21)
                        app.deck.dealerHand.cards = [app.deck.dealerHand.cards, app.deck.cards(1)];
                        app.deck.cards(1) = [];
                        app.xpos2 = app.xpos2 - 37;
                        app.deck.dealerHand.cards(end).privacy = 1;
                        app.deck.dealerHand.cards(end).imageApp = app.dealerCard(app.deck, app.xpos2);
                        dtotal = app.sumDealer;
                        while (dtotal > 21)
                            for i=1:length(app.deck.dealerHand.cards)
                                if app.deck.dealerHand.cards(i).numVal == "11"
                                    app.deck.dealerHand.cards(i).numVal = "1";
                                    dtotal = app.sumDealer(app.deck);
                                    break
                                end
                            end
                            if dtotal <= 21
                                continue
                            elseif dtotal > 21
                                break
                            end
                        end
                    end
                    if dtotal > 21
                        app.winScreen
                    else dtotal == 21
                        app.tieScreen
                    end
                end
            end
        end
        
        function total = sumHand(app, deck) %sum the value of cards in player's hand
            sumArray = [];
            for i = 1:length(app.deck.p1Hand.cards)
                sumArray = [sumArray, str2double(app.deck.p1Hand.cards(i).numVal)];
            end
            app.deck.p1Hand.sumCards = sum(sumArray);
            total = app.deck.p1Hand.sumCards;
        end
        
        
        function total = sumDealer(app, deck) %sum val of cards in dealer's hand
            sumArray = [];
            for i = 1:length(app.deck.dealerHand.cards)
                sumArray = [sumArray, str2double(app.deck.dealerHand.cards(i).numVal)];
            end
            app.deck.dealerHand.sumCards = sum(sumArray);
            total = app.deck.dealerHand.sumCards;
        end
        
        function bool = checkDealerBJ(app, deck) %check dealers hand for blackjack
            value = str2double(app.deck.dealerHand.cards(1).numVal) + str2double(app.deck.dealerHand.cards(2).numVal);
            if value == 21
                bool = true;
            else
                bool = false;
            end
        end
        
        function dealerDraw(app, deck) %check whether dealer should draw
            total = app.sumDealer(app.deck); %check value of dealer hand
            while total>21 %lowers value of aces if over 21
                for i=1:length(app.deck.dealerHand.cards)
                    if app.deck.dealerHand.cards(i).numVal == "11"
                        app.deck.dealerHand.cards(i).numVal = "1";
                        total = app.sumDealer(app.deck);
                        break
                    end
                end
                if total <= 21
                    continue
                elseif total > 21
                    break
                end
            end
            total = app.sumDealer(app.deck); %check new sum of hand
            while total < 17 %dealer logic: if the dealer is under 17, they hit, otherwise stand
                app.deck.dealerHand.cards = [app.deck.dealerHand.cards, app.deck.cards(1)];
                app.deck.cards(1) = [];
                app.xpos2 = app.xpos2 - 37;
                app.deck.dealerHand.cards(end).privacy = 1;
                app.deck.dealerHand.cards(end).imageApp = app.dealerCard(app.deck, app.xpos2);
                total = app.sumDealer(app.deck);
                if total > 21 %change value of aces as needed
                    while total > 21
                        for i=1:length(app.deck.dealerHand.cards)
                            if app.deck.dealerHand.cards(i).numVal == "11"
                                app.deck.dealerHand.cards(i).numVal = "1";
                                total = app.sumDealer(app.deck);
                                if total <= 21
                                    break
                                end
                            end
                        end
                        total = app.sumDealer(app.deck);
                        if total > 21
                            break
                        end
                    end
                    if total > 21 %if dealer busts, player wins
                        app.winScreen
                    end
                end
            end
        end
        
        function cardImage = cardDeal(app, deck, xpos, number) %set image of initial draw 2 
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos 26 90 112];
            if app.deck.p1Hand.cards(end).privacy == 1 %changes visibility of card
                cardImage.ImageSource = app.deck.p1Hand.cards(number).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(number).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end

        function cardImage = newCard(app, deck, xpos) %set image of add cards to hand on hit
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos 26 90 112];
            if app.deck.p1Hand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.p1Hand.cards(end).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(end).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
        
        function cardImage = dealerDeal(app, deck, xpos2, number) %set image of draw dealer's first 2 cardds
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos2 349 90 112];
            if app.deck.dealerHand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.dealerHand.cards(number).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.dealerHand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.dealerHand.cards(number).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
        
        function cardImage = dealerCard(app, deck, xpos2) %set image of dealer draws cards
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos2 349 90 112];
            if app.deck.dealerHand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.dealerHand.cards(end).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.dealerHand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.dealerHand.cards(end).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
        
        function flipDealerHand(app, deck) %flip dealer's cards from face down to face up
            app.deck.dealerHand.cards(1).privacy = 1;
            app.deck.dealerHand.cards(2).privacy = 1;
            app.deck.dealerHand.cards(1).imageApp = dealerDeal(app, app.deck, 347, 1);
            app.deck.dealerHand.cards(2).imageApp = dealerDeal(app, app.deck, 310, 2);
        end
        
        function compareValues(app, deck) %compare the values of the two hands
            player = app.sumHand(app.deck);
            dealer = app.sumDealer(app.deck);
            if player > dealer
                    while (dealer < player) && (dealer <= 21)
                        app.deck.dealerHand.cards = [app.deck.dealerHand.cards, app.deck.cards(1)];
                        app.deck.cards(1) = [];
                        app.xpos2 = app.xpos2 - 37;
                        app.deck.dealerHand.cards(end).privacy = 1;
                        app.deck.dealerHand.cards(end).imageApp = app.dealerCard(app.deck, app.xpos2);
                        dealer = app.sumDealer;
                        while (dealer > 21)
                            for i=1:length(app.deck.dealerHand.cards)
                                if app.deck.dealerHand.cards(i).numVal == "11"
                                    app.deck.dealerHand.cards(i).numVal = "1";
                                    dealer = app.sumDealer(app.deck);
                                    break
                                end
                            end
                            if dealer <= 21
                                continue
                            elseif dealer > 21
                                break
                            end
                        end
                    end
                    if dealer < player
                        app.winScreen
                    else dealer == player
                        app.tieScreen
                    end
            elseif player == dealer
                app.tieScreen
            else
                app.loseScreen
            end
        end
        
        function winScreen(app)
            [y, Fs] = audioread("money3.mp3");
            sound(y, Fs, 16)
            app.YouWonLabel.Visible="on"; %Bring up Win Message
            for i = 1:160 %animation
                app.YouWonLabel.Position=[173, i, 359, 258]; 
                pause(0.01)
            end
            app.BalanceEditField.Value = app.BalanceEditField.Value + 2 * app.BetEditField.Value; %add money won to balance
            app.PlayAgainButton.Enable = 'on';
            app.PlayAgainButton.Visible = 'on';
            app.QuitPlayingButton.Enable = 'on';
            app.QuitPlayingButton.Visible = 'on';
            app.DOUBLEDOWNButton.Enable = 'off';
            app.hitButton.Enable = 'off';
            app.StandButton.Enable = 'off';
            app.BetEditField.Editable = 'on';
            app.TextArea.Visible = 'off';
            app.BetEditField.Value = 0;
        end
        function tieScreen(app)
            app.YouTiedLabel.Visible="on"; %Bring up Loss Message
            for i = 1:160 %animation
                app.YouTiedLabel.Position=[173, i, 359, 258]; 
                pause(0.01)
            end
            app.BalanceEditField.Value = app.BalanceEditField.Value + app.BetEditField.Value;
            app.PlayAgainButton.Enable = 'on';
            app.PlayAgainButton.Visible = 'on';
            app.QuitPlayingButton.Enable = 'on';
            app.QuitPlayingButton.Visible = 'on';
            app.DOUBLEDOWNButton.Enable = 'off';
            app.hitButton.Enable = 'off';
            app.StandButton.Enable = 'off';
            app.BetEditField.Editable = 'on';
            app.TextArea.Visible = 'on';
            app.BetEditField.Value = 0;
        end
        function loseScreen(app)
            [y, Fs] = audioread("failure.mp3");
            sound(y, Fs, 16)
            app.YouLostLabel.Visible="on"; %Bring up Loss Message
            for i = 1:160 %animation
                app.YouLostLabel.Position=[173, i, 359, 258]; 
                pause(0.01)
            end
            app.DOUBLEDOWNButton.Enable = 'off';
            app.hitButton.Enable = 'off';
            app.StandButton.Enable = 'off';
            boolGameOver = app.isGameOver(app.deck);
            if boolGameOver == true
                app.gameOver(app.deck)
            elseif boolGameOver == false
            app.TextArea.Visible = 'on';
            app.PlayAgainButton.Enable = 'on';
            app.PlayAgainButton.Visible = 'on';
            app.QuitPlayingButton.Enable = 'on';
            app.QuitPlayingButton.Visible = 'on';
            app.BetEditField.Editable = 'on';
            app.BetEditField.Value = 0;
            end
        end
        
        function bool = isGameOver(app, deck)
            if app.BalanceEditField.Value <= 0
                bool = true;
            else
                bool = false;
            end
        end
        
        function gameOver(app, deck)
            app.GAMEOVERReplayButton.Visible = 'on';
            app.GAMEOVERReplayButton.Enable = 'on';
            app.QuitPlayingButton.Visible = 'on';
            app.QuitPlayingButton.Enable = 'on';
        end
        
        function deleteImages(app, deck)
            for i = 1:length(app.deck.p1Hand.cards)
                delete(app.deck.p1Hand.cards(i).imageApp);
            end
            for i = 1:length(app.deck.dealerHand.cards)
                delete(app.deck.dealerHand.cards(i).imageApp);
            end
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            import pkg.Deck.*
            import pkg.Card.*
            import pkg.Hand.*
            import pkg.DiscardPile.*
            app.BalanceEditField.Value=500;
            app.BetEditField.Value = 0;
        end

        % Button pushed function: DEALButton
        function dealButton(app, event)
            [y, Fs] = audioread("cardFlip.mp3");
            sound(y, Fs, 16)
            app.deck = Deck;
            app.DEALButton.Visible = 'off';
            app.DEALButton.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.deck.p1Hand.cards(1).imageApp = cardDeal(app, app.deck, 347, 1);
            app.deck.p1Hand.cards(2).imageApp = cardDeal(app, app.deck, 310, 2);
            app.deck.dealerHand.cards(1).imageApp = dealerDeal(app, app.deck, 347, 1);
            app.deck.dealerHand.cards(2).imageApp = dealerDeal(app, app.deck, 310, 2);
            app.hitButton.Enable = 'on';
            app.StandButton.Enable = 'on';
            app.BalanceEditField.Editable = "off";
            app.BetEditField.Editable="off";
            if app.BalanceEditField.Value < (app.BetEditField.Value * 2)
                app.DOUBLEDOWNButton.Enable = 'off';
            else
                app.DOUBLEDOWNButton.Enable = 'on';
            end
            app.BalanceEditField.Value=app.BalanceEditField.Value-app.BetEditField.Value;
            app.check21(app.deck)
        end

        % Button pushed function: hitButton
        function hitMe(app, event)
            [y, Fs] = audioread("cardFlip.mp3");
            sound(y, Fs, 16)
            app.DOUBLEDOWNButton.Enable = 'off';
            app.deck.p1Hand.cards = [app.deck.p1Hand.cards, app.deck.cards(1)];
            app.deck.p1Hand.cards(end).privacy = 1;
            app.deck.cards(1) = [];
            app.xpos = app.xpos - 37;
            app.deck.p1Hand.cards(end).imageApp = newCard(app, app.deck, app.xpos);
            app.check21(app.deck);
        end

        % Button pushed function: StandButton
        function standMe(app, event)
            app.check21(app.deck)
            app.flipDealerHand
            if (app.sumDealer < app.sumHand)
                app.dealerDraw(app.deck)
                if app.sumDealer <= 21
                    app.compareValues(app.deck)
                end
            else
                app.compareValues(app.deck)
            end
        end

        % Value changed function: BetEditField
        function ChangingBet(app, event)
            value = app.BetEditField.Value;
            if (app.BetEditField.Value > app.BalanceEditField.Value) || (app.BetEditField.Value <= 0)
                app.DEALButton.Enable = 'off';
                app.PlayAgainButton.Enable = 'off';
            else
                app.DEALButton.Enable = 'on';
                app.PlayAgainButton.Enable = 'on';
            end
        end

        % Button pushed function: PlayAgainButton
        function playAgain(app, event)
            app.PlayAgainButton.Enable = 'off';
            app.PlayAgainButton.Visible = 'off';
            app.QuitPlayingButton.Enable = 'off';
            app.QuitPlayingButton.Visible = 'off';
            app.YouWonLabel.Visible = 'off';
            app.YouTiedLabel.Visible = 'off';
            app.YouLostLabel.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.deleteImages(app.deck);
            app.xpos = 310;
            app.xpos2 = 310;
            app.deck = Deck;
            app.deck.p1Hand.cards(1).imageApp = cardDeal(app, app.deck, 347, 1);
            app.deck.p1Hand.cards(2).imageApp = cardDeal(app, app.deck, 310, 2);
            app.deck.dealerHand.cards(1).imageApp = dealerDeal(app, app.deck, 347, 1);
            app.deck.dealerHand.cards(2).imageApp = dealerDeal(app, app.deck, 310, 2);
            app.hitButton.Enable = 'on';
            app.StandButton.Enable = 'on';
            app.BalanceEditField.Editable = "off";
            app.BetEditField.Editable="off";
            if app.BalanceEditField.Value < (app.BetEditField.Value * 2)
                app.DOUBLEDOWNButton.Enable = 'off';
            else
                app.DOUBLEDOWNButton.Enable = 'on';
            end
            app.BalanceEditField.Value=app.BalanceEditField.Value-app.BetEditField.Value;
            app.check21(app.deck)
        end

        % Button pushed function: QuitPlayingButton
        function quitPlaying(app, event)
            closereq
        end

        % Button pushed function: GAMEOVERReplayButton
        function replayButton(app, event)
            delete(app.UIFigure)
            blackjack1()
        end

        % Button pushed function: DOUBLEDOWNButton
        function doubleDown(app, event)
            [y, Fs] = audioread("cardFlip.mp3");
            sound(y, Fs, 16)
            app.BalanceEditField.Value = app.BalanceEditField.Value - app.BetEditField.Value;
            app.BetEditField.Value = app.BetEditField.Value * 2;
            app.DOUBLEDOWNButton.Enable = 'off';
            app.deck.p1Hand.cards = [app.deck.p1Hand.cards, app.deck.cards(1)];
            app.deck.p1Hand.cards(end).privacy = 1;
            app.deck.cards(1) = [];
            app.xpos = app.xpos - 37;
            app.deck.p1Hand.cards(end).imageApp = newCard(app, app.deck, app.xpos);
            app.check21()
            if app.sumHand < 21
                app.standMe()
            end
        end

        % Value changed function: BalanceEditField
        function changingBalance(app, event)
            value = app.BalanceEditField.Value;
            if app.BalanceEditField.Value <= 0
                app.DEALButton.Enable = 'off';
            else
                app.DEALButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'blackjack-table-blackjack-table-png-300_189.png';
            app.hitButton = uibutton(app.UIFigure, 'push');
            app.hitButton.ButtonPushedFcn = createCallbackFcn(app, @hitMe, true);
            app.hitButton.Enable = 'off';
            app.hitButton.Text = {'Hit'; ''};
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @standMe, true);
            app.StandButton.Enable = 'off';
            app.StandButton.Text = {'Stand'; ''};
            app.DOUBLEDOWNButton = uibutton(app.UIFigure, 'push');
            app.DOUBLEDOWNButton.ButtonPushedFcn = createCallbackFcn(app, @doubleDown, true);
            app.DOUBLEDOWNButton.Enable = 'off';
            app.DOUBLEDOWNButton.Text = {'DOUBLE DOWN'; ''};
            app.DEALButton = uibutton(app.UIFigure, 'push');
            app.DEALButton.ButtonPushedFcn = createCallbackFcn(app, @dealButton, true);
            app.DEALButton.Text = {'Deal'; ''};
            app.DeckBack = uiimage(app.UIFigure);
            app.DeckBack.ImageSource = 'back.jpg';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'CHANGE YOUR BALANCE START'};
            app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.BalanceEditField.ValueChangedFcn = createCallbackFcn(app, @changingBalance, true);
            app.BetEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetEditField.ValueChangedFcn = createCallbackFcn(app, @ChangingBet, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'CHANGE YOUR BET HERE'};
            app.YouWonLabel = uilabel(app.UIFigure);
            app.YouWonLabel.Visible = 'off';
            app.YouWonLabel.Text = 'You Won!!!';
            app.YouLostLabel = uilabel(app.UIFigure);
            app.YouLostLabel.Visible = 'off';
            app.YouLostLabel.Text = 'You Lost :(';
            app.OutofMoneyLabel = uilabel(app.UIFigure);
            app.OutofMoneyLabel.Visible = 'off';
            app.OutofMoneyLabel.Text = 'Out of Money!!';
            app.YouTiedLabel = uilabel(app.UIFigure);
            app.YouTiedLabel.Visible = 'off';
            app.YouTiedLabel.Text = 'You Tied...';
            app.PlayAgainButton = uibutton(app.UIFigure, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @playAgain, true);
            app.PlayAgainButton.Enable = 'off';
            app.PlayAgainButton.Visible = 'off';
            app.PlayAgainButton.Text = 'Play Again?';
            app.QuitPlayingButton = uibutton(app.UIFigure, 'push');
            app.QuitPlayingButton.ButtonPushedFcn = createCallbackFcn(app, @quitPlaying, true);
            app.QuitPlayingButton.Enable = 'off';
            app.QuitPlayingButton.Visible = 'off';
            app.QuitPlayingButton.Text = 'Quit Playing?';
            app.GAMEOVERReplayButton = uibutton(app.UIFigure, 'push');
            app.GAMEOVERReplayButton.ButtonPushedFcn = createCallbackFcn(app, @replayButton, true);
            app.GAMEOVERReplayButton.Enable = 'off';
            app.GAMEOVERReplayButton.Visible = 'off';
            app.GAMEOVERReplayButton.Text = {'GAME OVER...'; 'Replay?'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end